classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OperatorLabel              matlab.ui.control.Label
        CurrentOperatorLabel       matlab.ui.control.Label
        FilenameLabel              matlab.ui.control.Label
        OperatorPanel              matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        CannyButton                matlab.ui.control.Button
        RobertsButton              matlab.ui.control.Button
        PrewittButton              matlab.ui.control.Button
        SobelButton                matlab.ui.control.Button
        LaplacianOfGaussianButton  matlab.ui.control.Button
        LaplaceButton              matlab.ui.control.Button
        UploadButton               matlab.ui.control.Button
        SegmentationAxes           matlab.ui.control.UIAxes
        EdgeAxes                   matlab.ui.control.UIAxes
        InputAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Img % Image Buffer
        Filename % Filename
    end

    methods (Access = private)
        
        function output = pre_process(app)
            switch app.Filename
                case "01.bmp"
                    output = app.Img(:,:,1)-app.Img(:,:,2);
                case "02.bmp"
                    output = app.Img(:,:,1)-app.Img(:,:,2);
                case "03.bmp"
                    output = app.Img(:,:,1)-app.Img(:,:,2);
                case "04.bmp"
                    output = app.Img(:,:,1)-app.Img(:,:,2);
                case "05.bmp"
                    output = imgaussfilt(im2gray(app.Img),.5);
                case "06.bmp"
                    output = im2gray(app.Img);
            end
        end
        
        function results = func2(app)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct;*.tif'}, 'Select File to Open','.\img\');
            fullname = [filepath, filename];
            app.Img = imread(fullname);
            app.FilenameLabel.Text = filename;
            app.Filename = filename;
            imshow(app.Img,'Parent',app.InputAxes);
            figure(app.UIFigure);
        end

        % Button pushed function: LaplaceButton
        function LaplaceButtonPushed(app, event)
%             app.LaplaceButton.BackgroundColor = 'b';
%             app.LaplaceButton.FontColor = 'w';
            app.OperatorLabel.Text = 'Laplace';
            img = app.pre_process();
            edgeD = laplace(img);
            imshow(edgeD,'Parent',app.EdgeAxes);

            switch app.Filename
                case "01.bmp"
                    output = segmentation(edgeD,'adaptive',0,30,true);
                case "02.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "03.bmp"
                    output = segmentation(edgeD,'adaptive',0,100,true);
                case "04.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "05.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "06.bmp"
                    output = segmentation(edgeD,'global',0,3,true);
            end

            oimg = rmv_bg(output,app.Img);
            imshow(oimg,'Parent',app.SegmentationAxes);
            figure(app.UIFigure);
        end

        % Button pushed function: LaplacianOfGaussianButton
        function LaplacianOfGaussianButtonPushed(app, event)
            app.OperatorLabel.Text = 'Laplacian of Gaussian';
            img = app.pre_process();
            edgeD = log(img);
            imshow(edgeD,'Parent',app.EdgeAxes);
            
            switch app.Filename
                case "01.bmp"
                    output = segmentation(edgeD,'global',0,3,true);
                case "02.bmp"
                    output = segmentation(edgeD,'adaptive',0,3,true);
                case "03.bmp"
                    output = segmentation(edgeD,'adaptive',0,60,true);
                case "04.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "05.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "06.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
            end

            oimg = rmv_bg(output,app.Img);
            imshow(oimg,'Parent',app.SegmentationAxes);
            figure(app.UIFigure);
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            app.OperatorLabel.Text = 'Sobel';
            img = app.pre_process();
            edgeD = sobel(img);
            imshow(edgeD,'Parent',app.EdgeAxes);
            
            switch app.Filename
                case "01.bmp"
                    output = segmentation(edgeD,'global',0,100,false);
                case "02.bmp"
                    output = segmentation(edgeD,'adaptive',0,50,false);
                case "03.bmp"
                    output = segmentation(edgeD,'global',0,100,true);
                case "04.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "05.bmp"
                    output = segmentation(edgeD,'adaptive',2,0,true);
                case "06.bmp"
                    output = segmentation(edgeD,'global',0,1000,true);
            end

            oimg = rmv_bg(output,app.Img);
            imshow(oimg,'Parent',app.SegmentationAxes);
            figure(app.UIFigure);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            app.OperatorLabel.Text = 'Prewitt';
            img = app.pre_process();
            edgeD = prewitt(img);
            imshow(edgeD,'Parent',app.EdgeAxes);
            
            switch app.Filename
                case "01.bmp"
                    output = segmentation(edgeD,'global',0,100,false);
                case "02.bmp"
                    output = segmentation(edgeD,'global',0,3,true);
                case "03.bmp"
                    output = segmentation(edgeD,'global',0,100,true);
                case "04.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "05.bmp"
                    output = segmentation(edgeD,'adaptive',2,0,true);
                case "06.bmp"
                    output = segmentation(edgeD,'global',0,1000,true);
            end

            oimg = rmv_bg(output,app.Img);
            imshow(oimg,'Parent',app.SegmentationAxes);
            figure(app.UIFigure);
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
            app.OperatorLabel.Text = 'Roberts';
            img = app.pre_process();
            edgeD = roberts(img);
            imshow(edgeD,'Parent',app.EdgeAxes);
            
            switch app.Filename
                case "01.bmp"
                    output = segmentation(edgeD,'global',0,15,true);
                case "02.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,false);
                case "03.bmp"
                    output = segmentation(edgeD,'global',0,100,true);
                case "04.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "05.bmp"
                    output = segmentation(edgeD,'adaptive',0,250,true);
                case "06.bmp"
                    output = segmentation(edgeD,'global',0,4,true);
            end

            oimg = rmv_bg(output,app.Img);
            imshow(oimg,'Parent',app.SegmentationAxes);
            figure(app.UIFigure);
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
            app.OperatorLabel.Text = 'Canny';
            img = app.pre_process();
            edgeD = canny(img);
            imshow(edgeD,'Parent',app.EdgeAxes);
            
            switch app.Filename
                case "01.bmp"
                    output = segmentation(edgeD,'adaptive',0,50,true);
                case "02.bmp"
                    output = segmentation(edgeD,'adaptive',0,50,true);
                case "03.bmp"
                    output = segmentation(edgeD,'adaptive',0,100,true);
                case "04.bmp"
                    output = segmentation(edgeD,'adaptive',0,20,true);
                case "05.bmp"
                    output = segmentation(edgeD,'adaptive',0,1,true);
                case "06.bmp"
                    output = segmentation(edgeD,'adaptive',0,1000,true);
            end

            oimg = rmv_bg(output,app.Img);
            imshow(oimg,'Parent',app.SegmentationAxes);
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputAxes = uiaxes(app.UIFigure);
            title(app.InputAxes, 'Input')
            app.InputAxes.XTick = [];
            app.InputAxes.YTick = [];
            app.EdgeAxes = uiaxes(app.UIFigure);
            title(app.EdgeAxes, 'Edge')
            app.EdgeAxes.XTick = [];
            app.EdgeAxes.YTick = [];
            app.SegmentationAxes = uiaxes(app.UIFigure);
            title(app.SegmentationAxes, 'Segmentation')
            app.SegmentationAxes.XTick = [];
            app.SegmentationAxes.YTick = [];
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.OperatorPanel = uipanel(app.UIFigure);
            app.OperatorPanel.Title = 'Operator';
            app.GridLayout = uigridlayout(app.OperatorPanel);
            app.LaplaceButton = uibutton(app.GridLayout, 'push');
            app.LaplaceButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceButtonPushed, true);
            app.LaplaceButton.Text = 'Laplace';
            app.LaplacianOfGaussianButton = uibutton(app.GridLayout, 'push');
            app.LaplacianOfGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianOfGaussianButtonPushed, true);
            app.LaplacianOfGaussianButton.Text = 'Laplacian Of Gaussian';
            app.SobelButton = uibutton(app.GridLayout, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uibutton(app.GridLayout, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uibutton(app.GridLayout, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.CannyButton = uibutton(app.GridLayout, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'Filename';
            app.CurrentOperatorLabel = uilabel(app.UIFigure);
            app.CurrentOperatorLabel.Text = 'Current Operator : ';
            app.OperatorLabel = uilabel(app.UIFigure);
            app.OperatorLabel.Text = 'Operator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end